classdef boundednoise_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        scalingfactorEditFieldLabel   matlab.ui.control.Label
        scalingfactorEditField        matlab.ui.control.NumericEditField
        frequencyEditFieldLabel       matlab.ui.control.Label
        frequencyEditField            matlab.ui.control.NumericEditField
        noiseintensityEditFieldLabel  matlab.ui.control.Label
        noiseintensityEditField       matlab.ui.control.NumericEditField
        OkButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, bn_sf, bn_w, bn_D)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.scalingfactorEditField.Value = bn_sf;
            app.frequencyEditField.Value = bn_w;
            app.noiseintensityEditField.Value = bn_D;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
           % Call main app's public function
           receive_boundednoise_parameter(app.CallingApp,app.scalingfactorEditField.Value,app.frequencyEditField.Value,app.noiseintensityEditField.Value);
        
           % Delete the dialog box  
           delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.CallingApp.boundednoiseMenu.Enable = 'on';
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.scalingfactorEditFieldLabel = uilabel(app.UIFigure);
            app.scalingfactorEditFieldLabel.Text = 'scaling factor';
            app.scalingfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.frequencyEditFieldLabel = uilabel(app.UIFigure);
            app.frequencyEditFieldLabel.Text = 'frequency';
            app.frequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.frequencyEditField.Value = 0.2;
            app.noiseintensityEditFieldLabel = uilabel(app.UIFigure);
            app.noiseintensityEditFieldLabel.Text = 'noise intensity';
            app.noiseintensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.noiseintensityEditField.Value = 1;
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
        end
    end

    methods (Access = public)

        % Construct app
        function app = boundednoise_parameter(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end