classdef fhn_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        timeEditFieldLabel          matlab.ui.control.Label
        timeEditField               matlab.ui.control.NumericEditField
        dtEditFieldLabel            matlab.ui.control.Label
        dtEditField                 matlab.ui.control.NumericEditField
        OKButton                    matlab.ui.control.Button
        I_backgroundEditFieldLabel  matlab.ui.control.Label
        I_backgroundEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, time, dt, I_background)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.timeEditField.Value = time;
            app.dtEditField.Value = dt;
            app.I_backgroundEditField.Value = I_background;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
           % Call main app's public function
           receive_fhn_parameter(app.CallingApp,app.timeEditField.Value,app.dtEditField.Value,app.I_backgroundEditField.Value);
        
           % Delete the dialog box  
           delete(app)     
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.CallingApp.fhnmodelMenu.Enable = 'on';
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.timeEditFieldLabel = uilabel(app.UIFigure);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.timeEditField.Limits = [0 10000];
            app.timeEditField.Value = 5;
            app.dtEditFieldLabel = uilabel(app.UIFigure);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.UIFigure, 'numeric');
            app.dtEditField.Value = 0.001;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.I_backgroundEditFieldLabel = uilabel(app.UIFigure);
            app.I_backgroundEditFieldLabel.Text = 'I_background';
            app.I_backgroundEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = fhn_parameter(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end