classdef neural_network_for_synchronous_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        neuronselectMenu            matlab.ui.container.Menu
        fhnmodelMenu                matlab.ui.container.Menu
        HHmodelMenu                 matlab.ui.container.Menu
        noiseMenu                   matlab.ui.container.Menu
        boundednoiseMenu            matlab.ui.container.Menu
        whitegaussiannoiseMenu      matlab.ui.container.Menu
        networkMenu                 matlab.ui.container.Menu
        small_worldMenu             matlab.ui.container.Menu
        analysisMenu                matlab.ui.container.Menu
        exportMenu                  matlab.ui.container.Menu
        to_workplaceMenu            matlab.ui.container.Menu
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        CombiningandcmputingButton  matlab.ui.control.Button
        viewButton                  matlab.ui.control.Button
        viewButton_2                matlab.ui.control.Button
    end

    
    properties (Access = private)
        time = 5 % 运行的总时间
        dt = 0.001 % 运行的步长
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %神经元
        neuron_num = 1 % 神经元的数量
        I_background = 0 % 背景电流
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        time_space_fire % 时空放电图
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %子窗口
        fhn_parameter_App % fhn的参数界面
        boundednoise_parameter_App % 有界噪声的参数界面
        wgn_parameter_APP % 高斯白噪声的参数界面
        smallworld_parameter_APP % 小世界的参数界面
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %噪声
        %有界噪声
        bn_sf = 0.0 % 比例因子
        bn_w = 0.2  % 频率
        bn_D = 1 % 初相位的噪声强度
        %高斯白噪
        wgn_sf = 0.0 % 比例因子
        wgn_D = 0 %噪声强度
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %网络参数
        adjacency_matrix = 0 % 邻接矩阵
        networ_sf = 0.0 % 
        %小世界网络参数
        smallworld_N = 20 % 小世界网络中节点的总数
        smallworld_K = 4 % 小世界网络中节点邻居数
        smallworld_p = 0.2; % 小世界网络中随机化重连的概率
        
        
    end
    
    methods (Access = public)
        
        function fhn_model(app) % fhn的放电模型
            % Store inputs as properties
            [app.neuron_num,~] = size(app.adjacency_matrix);
            fhn_X = zeros(app.time/app.dt,app.neuron_num); % fhn模型中的快变量
            fhn_Y = zeros(app.time/app.dt,app.neuron_num); % fhn模型中的慢变量
            WGN_wgn = wgn(app.time/app.dt,app.neuron_num,app.wgn_D);
            WGN_bn = wgn(app.time/app.dt,app.neuron_num,app.bn_D);
            % 使用欧拉解方程
            bar = waitbar(0,'constructing...'); 
            for j = 1:app.neuron_num
                for i=1:app.time/app.dt
                    fhn_X(i+1,j) = fhn_X(i,j) + app.dt*1/0.01*(fhn_X(i,j) - fhn_X(i,j)^3/3 - fhn_Y(i,j) + app.networ_sf*app.adjacency_matrix(:,j)'*(fhn_X(i,:) - fhn_X(i,j))' + app.I_background); 
                    fhn_Y(i+1,j) = fhn_Y(i,j) + app.dt*(fhn_X(i,j) + 1.02 + app.bn_sf*sin(app.bn_w*(i-1)*app.dt + WGN_bn(i,j)) + app.wgn_sf*WGN_wgn(i,j));
                end
                str=['computing...',num2str(100*j/app.neuron_num),'%'];
                waitbar(j/app.neuron_num,bar,str)
            end
            waitbar(1,bar,'finished...100%')
            app.time_space_fire = fhn_X;
            
        end
        
        function receive_fhn_parameter(app,time,dt,I_background) %接收子界面的fhn参数  
            % Store inputs as properties
            app.time = time;
            app.dt = dt;
            app.I_background = I_background;
            % Re-enable the Plot Options button
            app.fhnmodelMenu.Enable = 'on';
        end
        
        function receive_boundednoise_parameter(app,bn_sf,bn_w,bn_D) % 接受子界面的有界噪声的参数
            % Store inputs as properties
            app.bn_sf = bn_sf;
            app.bn_w = bn_w;
            app.bn_D = bn_D;
            % Re-enable the Plot Options button
            app.boundednoiseMenu.Enable = 'on';
            
        end
        
        function receive_wgn_parameter(app,wgn_sf,wgn_D) % 接收子单元的高斯白噪声的参数
            % Store inputs as properties
            app.wgn_sf = wgn_sf;
            app.wgn_D = wgn_D;
            % Re-enable the Plot Options button
            app.whitegaussiannoiseMenu.Enable = 'on';
        end
        
        function samllworld_network(app) % 小世界网络的创建
            N = app.smallworld_N;
            K = app.smallworld_K;
            p = app.smallworld_p;
            

            A=zeros(N);
            for i=1:N
            for j=i+1:i+K/2
            jj=j;
            if j>N
            jj=mod(j,N);
            end
            A(i,jj)=1;
            A(jj,i)=1;
            end
            end
             
            %WS小世界网络的代码
            for i=1:N
                for j=i+1:i+K/2
                    jj=j;
                    if j>N
                        jj=mod(j,N);
                    end
                    p1=rand(1,1);
                    if p1<p
                        A(i,jj)=0;A(jj,i)=0;A(i,i)=inf;a=find(A(i,:)==0);
                        rand_data=randi([1,length(a)],1,1);
                        jjj=a(rand_data);A(i,jjj)=1;A(jjj,i)=1;A(i,i)=0;
                    end
                end
            end
            app.adjacency_matrix = A;
        end
        
        function receive_smallworld_parameter(app,smallworld_N,smallworld_K,smallworld_p,networ_sf) % 接受小世界网络的参数
            % Store inputs as properties
            app.smallworld_N = smallworld_N;
            app.smallworld_K = smallworld_K;
            app.smallworld_p = smallworld_p;
            app.networ_sf = networ_sf;
            % Re-enable the Plot Options button
            app.small_worldMenu.Enable = 'on';
        end
    end
    

    methods (Access = private)

        % Menu selected function: fhnmodelMenu
        function fhnmodelMenuSelected(app, event)
            % Disable Plot Options button while dialog is open
            app.fhnmodelMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.fhn_parameter_App = fhn_parameter(app, app.time, app.dt,app.I_background);
        end

        % Button pushed function: viewButton
        function viewButtonPushed(app, event)
            app.adjacency_matrix = 0; % 每次点击view时保证是没有网络的
            fhn_model(app) % 调用fhn模型，以后加一个选择判断

            plot(app.UIAxes,0:app.dt:app.time,app.time_space_fire(:,1))% 画图
        end

        % Menu selected function: boundednoiseMenu
        function boundednoiseMenuSelected(app, event)
            % Disable Plot Options button while dialog is open
            app.boundednoiseMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.boundednoise_parameter_App = boundednoise_parameter(app, app.bn_sf, app.bn_w,app.bn_D);            
        end

        % Menu selected function: whitegaussiannoiseMenu
        function whitegaussiannoiseMenuSelected(app, event)
            % Disable Plot Options button while dialog is open
            app.whitegaussiannoiseMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.wgn_parameter_APP = wgn_parameter(app, app.wgn_sf,app.wgn_D); 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app.fhn_parameter_App)
            delete(app.boundednoise_parameter_App)
            delete(app.wgn_parameter_APP)
            delete(app.smallworld_parameter_APP)
            delete(app)
        end

        % Menu selected function: small_worldMenu
        function small_worldMenuSelected(app, event)
            % Disable Plot Options button while dialog is open
            app.small_worldMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.smallworld_parameter_APP = smallworld_parameter(app,app.smallworld_N,app.smallworld_K,app.smallworld_p,app.networ_sf);
        end

        % Button pushed function: viewButton_2
        function viewButton_2Pushed(app, event)
            A = app.adjacency_matrix;
            N = app.smallworld_N;
            angle=0:2*pi/N:2*pi-2*pi/N;
            x=100*sin(angle);
            y=100*cos(angle);
            plot(app.UIAxes_2,x,y,'ro','MarkerEdgeColor',[0.5,0.5,0.5],'MarkerFaceColor',[0.5,0.5,0.5],'MarkerSize',8);
            hold(app.UIAxes_2,'on');
            for i=1:N
            for j=i+1:N
            if A(i,j)~=0
            plot(app.UIAxes_2,[x(i),x(j)],[y(i),y(j)],'linewidth',1.2,'color',[0.5,0.5,0.5]);
            hold(app.UIAxes_2,'on');
            end
            end
            end
            
            axis(app.UIAxes_2,[-110,110,-110,110])
            hold(app.UIAxes_2,'off');
        end

        % Button pushed function: CombiningandcmputingButton
        function CombiningandcmputingButtonPushed(app, event)

            fhn_model(app) % 调用fhn模型，以后加一个选择判断
            
        end

        % Menu selected function: to_workplaceMenu
        function to_workplaceMenuSelected(app, event)
            assignin('base','time_space_fire',app.time_space_fire)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.neuronselectMenu = uimenu(app.UIFigure);
            app.neuronselectMenu.Text = 'neuronselect';
            app.fhnmodelMenu = uimenu(app.neuronselectMenu);
            app.fhnmodelMenu.MenuSelectedFcn = createCallbackFcn(app, @fhnmodelMenuSelected, true);
            app.fhnmodelMenu.Text = 'fhn model';
            app.HHmodelMenu = uimenu(app.neuronselectMenu);
            app.HHmodelMenu.Text = 'H-H model';
            app.noiseMenu = uimenu(app.UIFigure);
            app.noiseMenu.Text = 'noise';
            app.boundednoiseMenu = uimenu(app.noiseMenu);
            app.boundednoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @boundednoiseMenuSelected, true);
            app.boundednoiseMenu.Text = 'bounded noise';
            app.whitegaussiannoiseMenu = uimenu(app.noiseMenu);
            app.whitegaussiannoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @whitegaussiannoiseMenuSelected, true);
            app.whitegaussiannoiseMenu.Text = 'white gaussian noise';
            app.networkMenu = uimenu(app.UIFigure);
            app.networkMenu.Text = 'network';
            app.small_worldMenu = uimenu(app.networkMenu);
            app.small_worldMenu.MenuSelectedFcn = createCallbackFcn(app, @small_worldMenuSelected, true);
            app.small_worldMenu.Text = 'small_world';
            app.analysisMenu = uimenu(app.UIFigure);
            app.analysisMenu.Text = 'analysis';
            app.exportMenu = uimenu(app.UIFigure);
            app.exportMenu.Text = 'export';
            app.to_workplaceMenu = uimenu(app.exportMenu);
            app.to_workplaceMenu.MenuSelectedFcn = createCallbackFcn(app, @to_workplaceMenuSelected, true);
            app.to_workplaceMenu.Text = 'to_workplace';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'single neuron')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'network')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.CombiningandcmputingButton = uibutton(app.UIFigure, 'push');
            app.CombiningandcmputingButton.ButtonPushedFcn = createCallbackFcn(app, @CombiningandcmputingButtonPushed, true);
            app.CombiningandcmputingButton.Text = 'Combining and cmputing';
            app.viewButton = uibutton(app.UIFigure, 'push');
            app.viewButton.ButtonPushedFcn = createCallbackFcn(app, @viewButtonPushed, true);
            app.viewButton.Text = 'view';
            app.viewButton_2 = uibutton(app.UIFigure, 'push');
            app.viewButton_2.ButtonPushedFcn = createCallbackFcn(app, @viewButton_2Pushed, true);
            app.viewButton_2.Text = 'view';
        end
    end

    methods (Access = public)

        % Construct app
        function app = neural_network_for_synchronous_analysis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end