classdef smallworld_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        NEditFieldLabel   matlab.ui.control.Label
        NEditField        matlab.ui.control.NumericEditField
        KEditFieldLabel   matlab.ui.control.Label
        KEditField        matlab.ui.control.NumericEditField
        pEditFieldLabel   matlab.ui.control.Label
        pEditField        matlab.ui.control.NumericEditField
        OkButton          matlab.ui.control.Button
        sfEditFieldLabel  matlab.ui.control.Label
        sfEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, N, K, p, sf)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.NEditField.Value = N;
            app.KEditField.Value = K;
            app.pEditField.Value = p;
            app.sfEditField.Value = sf;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
           % Call main app's public function
           receive_smallworld_parameter(app.CallingApp,app.NEditField.Value,app.KEditField.Value,app.pEditField.Value,app.sfEditField.Value);
           samllworld_network(app.CallingApp)
           % Delete the dialog box  
           delete(app)     
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.CallingApp.small_worldMenu.Enable = 'on';
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.pEditFieldLabel = uilabel(app.UIFigure);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.UIFigure, 'numeric');
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.sfEditFieldLabel = uilabel(app.UIFigure);
            app.sfEditFieldLabel.Text = 'sf';
            app.sfEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = smallworld_parameter(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end